classdef Dataplotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LegendCheckBox       matlab.ui.control.CheckBox
        PLOTButton           matlab.ui.control.Button
        ColorEditFieldLabel  matlab.ui.control.Label
        ColorEditField       matlab.ui.control.EditField
        DataDropDownLabel    matlab.ui.control.Label
        DataDropDown         matlab.ui.control.DropDown
        XaxisSlider_2Label   matlab.ui.control.Label
        XaxisSlider_2        matlab.ui.control.Slider
        YaxisSliderLabel     matlab.ui.control.Label
        YaxisSlider          matlab.ui.control.Slider
        ImportdataButton     matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        temp1
        time1
        temp2
        humid
        time2
        a
        l
        c = "b"
        b
        x_value
        y_value
        x_min
        y_min
        x_array
        y_array
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            load("sensor1.mat")
            load("sensor2.mat")
            app.temp1 = table2array(sensor1(: , 1));
            app.time1 = table2array(sensor1(: , 2));
            app.temp2 = table2array(sensor2(: , 2));
            app.humid = table2array(sensor2(: , 1));
            app.time2 = table2array(sensor2(: , 3));
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            if str2double(app.a) == 1
                plot(app.UIAxes, app.time1 , app.temp1, app.c)
            elseif str2double(app.a) == 2
                plot(app.UIAxes, app.time2 , app.temp2, app.c)
            elseif str2double(app.a) == 3
                plot(app.UIAxes, app.time2 , app.humid, app.c)
            end
            
            LegendCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            app.a = app.DataDropDown.Value;
            
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            app.l = app.LegendCheckBox.Value;
            if app.l == 1
                if str2double(app.a) == 1
                    legend(app.UIAxes, "Temperature 1")
                elseif str2double(app.a) == 2
                    legend(app.UIAxes, "Temperature 2")
                elseif str2double(app.a) == 3
                    legend(app.UIAxes, "Humidity")
                end


            else
                legend(app.UIAxes, "off")
            end
            
        end

        % Value changed function: ColorEditField
        function ColorEditFieldValueChanged(app, event)
            app.c = app.ColorEditField.Value;
   
        end

        % Value changed function: XaxisSlider_2
        function XaxisSlider_2ValueChanged(app, event)
%             app.x_value = app.XaxisSlider_2.Value;
%             if str2double(app.a) == 1
%                 app.x_min = min(app.time1);
%             else
%                 app.x_min = min(app.time2);
%             end
%             
%             app.x_array = [app.x_min app.x_value];
%             xlim(app.UIAxes, app.x_array)

        end

        % Value changed function: YaxisSlider
        function YaxisSliderValueChanged(app, event)
            app.y_value = app.YaxisSlider.Value;
             if str2double(app.a) == 1
                app.y_min = min(app.temp1);
             elseif str2double(app.a) == 2
                app.y_min = min(app.temp2);
             elseif str2double(app.a) == 3
                app.y_min = min(app.humid);
             end
             
            app.y_array = [app.y_min app.y_value];
            ylim(app.UIAxes, app.y_array)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LegendCheckBox = uicheckbox(app.UIFigure);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'text');
            app.ColorEditField.ValueChangedFcn = createCallbackFcn(app, @ColorEditFieldValueChanged, true);
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {'Temperature1', 'Temperature2', 'Humidity'};
            app.DataDropDown.ItemsData = {'1', '2', '3'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = '1';
            app.XaxisSlider_2Label = uilabel(app.UIFigure);
            app.XaxisSlider_2Label.Text = 'X-axis';
            app.XaxisSlider_2 = uislider(app.UIFigure);
            app.XaxisSlider_2.ValueChangedFcn = createCallbackFcn(app, @XaxisSlider_2ValueChanged, true);
            app.YaxisSliderLabel = uilabel(app.UIFigure);
            app.YaxisSliderLabel.Text = 'Y-axis';
            app.YaxisSlider = uislider(app.UIFigure);
            app.YaxisSlider.ValueChangedFcn = createCallbackFcn(app, @YaxisSliderValueChanged, true);
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dataplotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end